classdef IRIS_Imager_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IRIS                           matlab.ui.Figure
        Image                          matlab.ui.control.Image
        RunIRISImagerButton            matlab.ui.control.Button
        ProgramOutputLabel             matlab.ui.control.Label
        OutputText                     matlab.ui.control.TextArea
        version12March2021Label        matlab.ui.control.Label
        File                           matlab.ui.control.Button
        AdjustableParametersfileLabel  matlab.ui.control.Label
        Inputfile                      matlab.ui.control.EditField
        ReadUserGuideFirstLocatedinInstallDirectoryApplicationLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunIRISImagerButton
        function IRIS_Imager(app, event)
            IRIS_Film_Scan_0_22_app
        end

        % Close request function: IRIS
        function IRISCloseRequest(app, event)
            delete(app)
            quit
        end

        % Button pushed function: File
        function FileButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.xlsx;*.xls;*.csv','Spreadsheets (*.xlsx,*.xls,*.csv)'},...
                'Select adjustable parameters file');
            fullfileinput=fullfile(pathname,filename);
            app.Inputfile.Value=fullfileinput;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IRIS = uifigure('Visible', 'off');
            app.IRIS.Name = 'IRIS Imager';
            app.IRIS.CloseRequestFcn = createCallbackFcn(app, @IRISCloseRequest, true);
            app.Image = uiimage(app.IRIS);
            app.Image.ImageSource = 'IRIS_Imager_logo2.png';
            app.RunIRISImagerButton = uibutton(app.IRIS, 'push');
            app.RunIRISImagerButton.ButtonPushedFcn = createCallbackFcn(app, @IRIS_Imager, true);
            app.RunIRISImagerButton.Text = 'Run IRIS Imager';
            app.ProgramOutputLabel = uilabel(app.IRIS);
            app.ProgramOutputLabel.Text = 'Program Output';
            app.OutputText = uitextarea(app.IRIS);
            app.OutputText.Editable = 'off';
            app.version12March2021Label = uilabel(app.IRIS);
            app.version12March2021Label.Text = 'version 1.2 - March 2021';
            app.File = uibutton(app.IRIS, 'push');
            app.File.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.File.Text = 'Load Adjustable Parameters file';
            app.AdjustableParametersfileLabel = uilabel(app.IRIS);
            app.AdjustableParametersfileLabel.Text = 'Adjustable Parameters file:';
            app.Inputfile = uieditfield(app.IRIS, 'text');
            app.Inputfile.Editable = 'off';
            app.ReadUserGuideFirstLocatedinInstallDirectoryApplicationLabel = uilabel(app.IRIS);
            app.ReadUserGuideFirstLocatedinInstallDirectoryApplicationLabel.Text = 'Read User Guide First -- Located in Install Directory\Application';
            % Show the figure after all components are created
            app.IRIS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRIS_Imager_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IRIS)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IRIS)
        end
    end
end